classdef appCH_images < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageDetectUIFigure     matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        NeuralNetworkTab        matlab.ui.container.Tab
        ManualDrawPanel         matlab.ui.container.Panel
        ResetDrawing            matlab.ui.control.Button
        Draw_Axes               matlab.ui.control.UIAxes
        Panel_cover             matlab.ui.container.Panel
        Image30                 matlab.ui.control.Image
        Image32_2               matlab.ui.control.Image
        ShowGraphs              matlab.ui.container.Panel
        Panel_5                 matlab.ui.container.Panel
        ShapeVal                matlab.ui.control.Label
        ShapeLabel              matlab.ui.control.Label
        NewDrawBTN              matlab.ui.control.Button
        Infos_text              matlab.ui.control.Label
        InfosLabel              matlab.ui.control.Label
        Epochs_Text             matlab.ui.control.Label
        Ratios_Text             matlab.ui.control.Label
        EpochsLabel             matlab.ui.control.Label
        RatiosLabel             matlab.ui.control.Label
        Direitos1               matlab.ui.control.Label
        ISEC                    matlab.ui.control.Image
        Accuraccy_Text          matlab.ui.control.Label
        LayersLabel             matlab.ui.control.Label
        LayersLabel_text        matlab.ui.control.Label
        AccuraccyLabel          matlab.ui.control.Label
        DrawImage               matlab.ui.control.UIAxes
        ConfusionAxes1          matlab.ui.control.UIAxes
        PerformanceAxes2        matlab.ui.control.UIAxes
        Panel_6                 matlab.ui.container.Panel
        Lateral_Pannel          matlab.ui.container.Panel
        NeuralNetworkLabel      matlab.ui.control.Label
        NeuralNetworkDropDown   matlab.ui.control.DropDown
        Func3DropDown           matlab.ui.control.DropDown
        Func3DropDownLabel      matlab.ui.control.Label
        Func2DropDown           matlab.ui.control.DropDown
        Func2DropDownLabel      matlab.ui.control.Label
        Func1DropDown           matlab.ui.control.DropDown
        Func1DropDownLabel      matlab.ui.control.Label
        LayersPanel             matlab.ui.container.Panel
        Layer3Spinner           matlab.ui.control.Spinner
        Layer3SpinnerLabel      matlab.ui.control.Label
        Layer2Spinner           matlab.ui.control.Spinner
        Layer2SpinnerLabel      matlab.ui.control.Label
        Layer1Spinner           matlab.ui.control.Spinner
        Layer1SpinnerLabel      matlab.ui.control.Label
        RatiosPanel             matlab.ui.container.Panel
        TestSpinner             matlab.ui.control.Spinner
        TestSpinnerLabel        matlab.ui.control.Label
        validateSpinner         matlab.ui.control.Spinner
        validateSpinnerLabel    matlab.ui.control.Label
        TrainSpinner            matlab.ui.control.Spinner
        TrainSpinnerLabel       matlab.ui.control.Label
        PhaseButtonGroup        matlab.ui.container.ButtonGroup
        ManualButton            matlab.ui.control.RadioButton
        TestButton              matlab.ui.control.RadioButton
        TrainButton             matlab.ui.control.RadioButton
        StartButton             matlab.ui.control.RadioButton
        EpochsSlider            matlab.ui.control.Slider
        EpochsSliderLabel       matlab.ui.control.Label
        TrainFuncDropDown       matlab.ui.control.DropDown
        TrainFuncDropDownLabel  matlab.ui.control.Label
        BackButton              matlab.ui.control.Button
        SubmitButton_2          matlab.ui.control.Button
        DefaultsButton          matlab.ui.control.StateButton
        InputsButton            matlab.ui.control.Button
        OutputsButton           matlab.ui.control.Button
        ReportTab               matlab.ui.container.Tab
        Panel_3                 matlab.ui.container.Panel
        Relatorio               matlab.ui.control.StateButton
        HTML2                   matlab.ui.control.HTML
        AssignementTab          matlab.ui.container.Tab
        Panel_7                 matlab.ui.container.Panel
        Enunciado               matlab.ui.control.StateButton
        HTML2_2                 matlab.ui.control.HTML
        StudyResultsTab         matlab.ui.container.Tab
        Lamp                    matlab.ui.control.Lamp
        OpenFileButton          matlab.ui.control.StateButton
        UITable                 matlab.ui.control.Table
        TeamTab                 matlab.ui.container.Tab
        Autores                 matlab.ui.container.Panel
        ForumButton_2           matlab.ui.control.Button
        Image36                 matlab.ui.control.Image
        Image37                 matlab.ui.control.Image
        Image38                 matlab.ui.control.Image
        Image34                 matlab.ui.control.Image
        Image35                 matlab.ui.control.Image
        Hyperlink3              matlab.ui.control.Hyperlink
        Hyperlink2              matlab.ui.control.Hyperlink
        Hyperlink               matlab.ui.control.Hyperlink
        Label_4                 matlab.ui.control.Label
        obrigado                matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        Direitos2               matlab.ui.control.Label
        HugoFerreira            matlab.ui.control.Label
        CarlosSantos            matlab.ui.control.Label
        Thp                     matlab.ui.control.Image
    end


    methods (Access = private)

        function bAtualizarSTART(app, ~)
            app.ShowGraphs.Visible = 'off';
            app.Panel_cover.Visible  = 'on';
            app.ManualDrawPanel.Visible ='off';

        end


        function evalSubmit(app, ~)
            sumRatio=app.TrainSpinner.Value + app.validateSpinner.Value+app.TestSpinner.Value;

            if(sumRatio >100)
                app.TrainSpinner.BackgroundColor = [1.00,0.19,0.19];
                app.validateSpinner.BackgroundColor = [1.00,0.19,0.19];
                app.TestSpinner.BackgroundColor = [1.00,0.19,0.19];
            end

            if (sumRatio <100)
                app.TrainSpinner.BackgroundColor = [1.00,0.59,0.05];
                app.validateSpinner.BackgroundColor = [1.00,0.59,0.05];
                app.TestSpinner.BackgroundColor = [1.00,0.59,0.05];
            end

            if (sumRatio == 100)
                app.TrainSpinner.BackgroundColor = [0.85,0.90,0.91];
                app.validateSpinner.BackgroundColor = [0.85,0.90,0.91];
                app.TestSpinner.BackgroundColor = [0.85,0.90,0.91];
            end
            if(app.Lateral_Pannel.Enable=="off")
                launchAll(app, []);
            end
            app.ShowGraphs.Visible = 'on';
            app.Panel_cover.Visible = 'off';
        end

        function draw(app, ~)

            pause(0.5);

            cla(app.Draw_Axes,'reset')
            set(app.Draw_Axes,'Color','none')
            app.Draw_Axes.XAxis.Visible = 'off';
            app.Draw_Axes.YAxis.Visible = 'off';
            figure('visible','off');

            hFH = drawfreehand(app.Draw_Axes,'color','green');

            xy = hFH.Position;

            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);

            hFig1 = plot(xCoordinates, yCoordinates, 'bo-', 'LineWidth', 2);
            set(gca, 'Visible', 'off')
            saveas(hFig1, 'Photos/Manual/Img-Manual-1.png')


            copyobj(hFig1, app.Draw_Axes);
            delete(hFig1);

            delete(hFH);
            %delete('1');


        end


        function launchAll(app, ~)
            figure('visible','off');

                if(app.NeuralNetworkDropDown.Value == "Default")
                     network = "extras/TrainPolig1_trainlm_1_0_3_10_T_0.15_V_0.15_Tr_0.7.mat";
                else
                     network = "Out/Redes/" + app.NeuralNetworkDropDown.Value;
                end

            if(app.StartButton.Value==1)
                list = dir('Photos/start/**/*.png');
                phaseimg = "start";
            end

            if(app.TrainButton.Value==1)

                list = dir('Photos/train/**/*.png');
                phaseimg = "train";
            end

            if(app.TestButton.Value==1)

                list = dir('Photos/test/**/*.png');
                phaseimg = "test";

                load (network, 'net');
            end

            if(app.ManualButton.Value==1)
                list = dir('Photos/Manual/Img-Manual-1.png');

                phaseimg = "Manual";
    
                load (network, 'net');

            end

            filenames = string({list.folder}) + '/'+string({list.name});
            str = filenames;
            i=1;
            count = 0;
            for st = str
                count = count +1;
            end
            img_res = [28 28]; %Size of image base to use
            poligonos = zeros(img_res(1)*img_res(2)*3,count); % Initialize the matrix with zeros

            for st = str
                numChar = strfind(st,"."); %Number of char in image name before the point
                S = extractBefore(st, numChar); % extract only the name without extension
                numChar1 = strfind(S,"/"); %Number of char in image name before the /
                St2 = extractBefore(S, numChar1); % extract only the name
                numChar2 = strfind(S,"/"); %Number of char in image name before the /
                St3 = extractAfter(S, numChar2); % extract only the name
                numChar3 = strfind(St3,"-"); %Number of char in image name before the /
                St4 = extractAfter(St3, numChar3(1)); % extract only the name
                numChar4 = strfind(St4,"-"); %Number of char in image name before the /
                St5 = extractBefore(St4, numChar4); % extract only the name

                if(St5 == phaseimg)
                    fileStList = St2+'\'+St3+'.png';

                    I = imread(fileStList); %access image

                    I = imresize(I,img_res); %resize or image

                    BinImage = imbinarize(I); size(BinImage);

                    poligonos(:,i) = reshape(BinImage, 1, []);

                    fileout = "out/Binary/" + St3 + ".bin";

                    %open the file
                    fileID = fopen (fileout, 'w'); %creates bin file

                    %image conversion to black and white
                    %BW = im2bw(I, 0.4);

                    %write into the file
                    fprintf(fileID, '%d', poligonos(:,i)); %print to file


                    %show comparisons
                    %figure;
                    %imshowpair(I, BW, 'montage') %Shows image

                    i=i+1;
                end
            end


            if(app.StartButton.Value==1)


                letrasTarget = [1 1 1 1 1 ...
                    2 2 2 2 2 ...
                    3 3 3 3 3 ...
                    4 4 4 4 4 ...
                    5 5 5 5 5 ...
                    6 6 6 6 6 ...
                    ];

            end

            if(app.TrainButton.Value==1)

                vec1 = repelem(1, 50);
                vec2 = repelem(2, 50);
                vec3 = repelem(3, 50);
                vec4 = repelem(4, 50);
                vec5 = repelem(5, 50);
                vec6 = repelem(6, 50);
                letrasTarget = [vec1, vec2, vec3, vec4, vec5, vec6];


            end

            if(app.TestButton.Value==1)

                vec1 = repelem(1, 10);
                vec2 = repelem(2, 10);
                vec3 = repelem(3, 10);
                vec4 = repelem(4, 10);
                vec5 = repelem(5, 10);
                vec6 = repelem(6, 10);
                letrasTarget = [vec1, vec2, vec3, vec4, vec5, vec6];

            end

            if(app.ManualButton.Value==1)

                letrasTarget = ones(6,1);
                %letrasTarget = 1;
                %etrasTarget = [1 1 1 1 1 1]
            else
                letrasTarget = onehotencode(letrasTarget,1,'ClassNames',1:6);
            end


            % CEATE AND CONFIGURE the neural network
            % Fill: Number od hidden layers and nodes by hidden layer


            if(app.ManualButton.Value~=1 && app.TestButton.Value~=1)
                if(app.Layer3Spinner.Value ==0 && app.Layer2Spinner.Value >0)
                    net = feedforwardnet([app.Layer1Spinner.Value app.Layer2Spinner.Value]);
                    % Fill: Activation functions for the hidden and output layers; {'purelin', 'logsig', 'tansig'}
                    net.layers{1}.transferFcn = app.Func1DropDown.Value;
                    net.layers{2}.transferFcn = app.Func2DropDown.Value;

                elseif(app.Layer3Spinner.Value >0)
                    net = feedforwardnet([app.Layer1Spinner.Value app.Layer2Spinner.Value app.Layer3Spinner.Value]);
                    % Fill: Activation functions for the hidden and output layers; {'purelin', 'logsig', 'tansig'}
                    net.layers{1}.transferFcn = app.Func1DropDown.Value;
                    net.layers{2}.transferFcn = app.Func2DropDown.Value;
                    net.layers{3}.transferFcn = app.Func3DropDown.Value;
                else
                    net = feedforwardnet(app.Layer1Spinner.Value);
                    % Fill: Activation functions for the hidden and output layers; {'purelin', 'logsig', 'tansig'}
                    net.layers{1}.transferFcn = app.Func1DropDown.Value;
                end

                % Fill: Split of the sets examples training, validation and test
                net.divideFcn = 'dividerand';


                % Fill: Type of trainning function: {'NOO trainlm', 'NOOO trainbfg', traingd, traingdx'}
                net.trainFcn = app.TrainFuncDropDown.Value;

                sumRatio=app.TrainSpinner.Value + app.validateSpinner.Value+app.TestSpinner.Value;

                while(sumRatio~=100)
                    sumRatio=app.TrainSpinner.Value + app.validateSpinner.Value+app.TestSpinner.Value;

                    net.divideParam.trainRatio = app.TrainSpinner.Value/100;
                    net.divideParam.valRatio = app.validateSpinner.Value/100;
                    net.divideParam.testRatio = app.TestSpinner.Value/100;
                end

                net.trainParam.epochs = round(app.EpochsSlider.Value);

                net.trainParam.showWindow = 0;
                [net,tr] = train(net, poligonos, letrasTarget);

                disp(tr);
            end

            out = net(poligonos);


            r = 0;
            for i=1:size(out,2)
                [~, b] = max(out(:,i));
                [~, d] = max(letrasTarget(:,i));


                    if(b==1)
                        shape = "circle";
                        
                    elseif(b==2)
                        shape = "kite";
                    elseif(b==3)
                        shape = "parallelogram";
                    elseif(b==4)
                        shape = "square";
                    elseif(b==5)
                        shape = "trapezoid";
                    elseif(b==6)
                        shape = "triangle";
                    else
                        shape = "Not Found";
                    end

                    app.ShapeVal.Text = shape;
                if b == d
                    r = r+1;
                                             
                end
            end
                 if (r==0)
                     shape = 'Maybe a ' + shape + '?';

                     app.ShapeVal.Text = shape;
                 end
            

            if(app.TrainButton.Value==1)

                TTargets = letrasTarget(tr.testInd);
                out_test = (out(tr.testInd)>0.5);
                accuracyTotal = sum(out_test == TTargets)/length(tr.testInd);
                fprintf('Precisao total = %f\n', accuracyTotal*100);

                TInput = poligonos(:, tr.testInd);
                TTargetss = letrasTarget(:, tr.testInd);

                out = sim(net, TInput);

                r=0;
                for i=1:size(tr.testInd,2)
                    [~, b] = max(out(:,i));
                    [~, d] = max(TTargetss(:,i));

                    if b == d
                        r = r+1;

                    end
                end


                accuracy = r/size(tr.testInd,2);
                fprintf('Precisao teste = %f\n', accuracy*100);


            else
                if(app.ManualButton.Value~=1 && app.TestButton.Value~=1)
                    TTargets = letrasTarget(tr.testInd);
                    out_test = (out(tr.testInd)>0.5);
                    accuracy = sum(out_test == TTargets)/length(tr.testInd);
                    fprintf('Precisao total = %f\n', accuracy*100);

                else
                    accuracy = r/size(out,2);
                    fprintf('Precisao total = %f\n', accuracy*100);
                end


            end


            if(accuracy == 1)
                S1 = 1;
                S2 = 0;
            elseif(accuracy == 0)
                S1 = 0;
                S2 = 0;
            else
                numChar = strfind(num2str(round(accuracy, 2)),'.'); %Number of char in accuracy before the point
                S1 = extractBefore(num2str(round(accuracy, 2)), numChar);  % extract only the name without extension
                S2 = extractAfter(num2str(round(accuracy, 2)), numChar); % extract only the name without extension
            end

            nameParams =  net.trainFcn+ "_" + S1 + "_" + S2 + "_"  + num2str(app.Layer1Spinner.Value)+ "_" +num2str(app.Layer2Spinner.Value)+ "_"+num2str(app.Layer3Spinner.Value) + "_" + "T_" + num2str(app.TestSpinner.Value)+ "_" + "V_" + num2str(app.validateSpinner.Value) + "_" + "Tr_" + num2str(app.TrainSpinner.Value);

            tituloRede = "out/Redes/TrainPolig1_" + nameParams +".mat";


            tituloConfusion= "out/Confusion/Start_Confusion_"  + nameParams+ ".fig";
            tituloPerformance = "out/Performance/Start_Performance_"  + nameParams+ ".fig";
            tituloTrainState= "out/TrainState/Train_TrainState_"  + nameParams+ ".fig";


            if(app.StartButton.Value~=1)
                if(app.ManualButton.Value~=1 && app.TestButton.Value~=1)
                    hFig1 = plotconfusion(TTargetss, out);
                else
                    hFig1 = plotconfusion(letrasTarget, out);
                end

                savefig(tituloConfusion);

                % plots in most recent axis by default (nax2)
                set(findobj(gca,'type','text'),'fontsize',7);
                ax1 = findobj(hFig1,'Type','Axes');  % assuming 1 and only 1 handle is returned
                app.ConfusionAxes1.XAxis.Visible = 'off';
                app.ConfusionAxes1.YAxis.Visible = 'off';

                copyobj(ax1.Children, app.ConfusionAxes1);

                delete(hFig1);
            end

            if(app.ManualButton.Value~=1 && app.TestButton.Value~=1)

                hFig2 = plotperform(tr);
                savefig(tituloPerformance);
                ax2 = findobj(hFig2,'Type','Axes');  % assuming 1 and only 1 handle is returned

                copyobj(ax2.Children, app.PerformanceAxes2);
                delete(hFig2);


                hFig3 = plottrainstate(tr);
                savefig(tituloTrainState);

                delete (hFig3)


                save(tituloRede, 'net');
            end
            phase = "";

            if(app.StartButton.Value ==1)
                phase = "Start";

            elseif(app.TrainButton.Value ==1)
                phase = "Train";

            elseif(app.TestButton.Value == 1)
                phase = "Test";

            elseif(app.ManualButton.Value ==1)
                phase = "Manual";
            end
            app.Lateral_Pannel.Enable="on";


            infos = "Phase: " + phase + newline + " Training function: " + net.trainFcn;


            app.Infos_text.Text = infos;
            app.Accuraccy_Text.Text = num2str(round(accuracy, 2)*100)+"%";


            %net.trainParam.epochs
            ratios = "Train Ratio:      " + num2str(net.divideParam.trainRatio) + newline +"Validate Ratio: " + num2str(net.divideParam.valRatio)  + newline +"Test Ratio:       " + net.divideParam.testRatio;
            app.Ratios_Text.Text= ratios;

            epochs = num2str(net.trainParam.epochs);
            app.Epochs_Text.Text = epochs;
            if(app.ManualButton.Value~=1 && app.TestButton.Value~=1)
                layers =  " 1 - " + num2str(app.Layer1Spinner.Value)+ " : " + app.Func1DropDown.Value+ newline +  " 2 - "  + num2str(app.Layer2Spinner.Value)+ " : " + app.Func2DropDown.Value+ newline+ " 3 - " +num2str(app.Layer3Spinner.Value)+ " : " + app.Func3DropDown.Value;

                app.LayersLabel_text.Text = layers;

            end
            app.ManualDrawPanel.Visible ='off';
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LayerSpinnerValueChanged(app, []);
            try
                bAtualizarSTART(app, [])
            catch ME
                disp(ME);
                fprintf('Retrying...\n');
                bAtualizarSTART(app, [])
            end
        end

        % Button pushed function: SubmitButton_2
        function SubmitButtonPushed(app, event)
            try

                sumRatio=app.TrainSpinner.Value + app.validateSpinner.Value+app.TestSpinner.Value;

                if(sumRatio >100)
                    app.TrainSpinner.BackgroundColor = [1.00,0.20,0.20];
                    app.validateSpinner.BackgroundColor = [1.00,0.20,0.20];
                    app.TestSpinner.BackgroundColor = [1.00,0.20,0.20];
                end

                if (sumRatio <100)
                    app.TrainSpinner.BackgroundColor = [1.00,0.65,0.40];
                    app.validateSpinner.BackgroundColor = [1.00,0.65,0.40];
                    app.TestSpinner.BackgroundColor = [1.00,0.65,0.40];
                end


                if(sumRatio ==100)


                    app.TrainSpinner.BackgroundColor = [0.85,0.90,0.91];
                    app.validateSpinner.BackgroundColor = [0.85,0.90,0.91];
                    app.TestSpinner.BackgroundColor = [0.85,0.90,0.91];

                    cla(app.PerformanceAxes2,'reset');
                    set(app.PerformanceAxes2,'Color','none');
                    app.Draw_Axes.XAxis.Visible = 'off';
                    app.Draw_Axes.YAxis.Visible = 'off';
                    if (app.ManualButton.Value == 1 || app.TestButton.Value == 1)
                        app.PerformanceAxes2.Visible=0;
                    end


                    if(app.ManualButton.Value ~= 1)
                        app.Panel_cover.Visible  = 'on';
                        app.ShowGraphs.Visible  = 'off';
    
                        
                         app.NewDrawBTN.Visible="off";
                         app.DrawImage.Visible="off";
                         app.ShapeVal.Visible = "off";
                         app.ShapeLabel.Visible = "off";
                                                               cla(app.DrawImage,'reset')
            set(app.DrawImage,'Color','none')
                                    app.DrawImage.XAxis.Visible = 'off';
                        app.DrawImage.YAxis.Visible = 'off';
                    else
                        app.Panel_cover.Visible  = 'off';
                        app.ShowGraphs.Visible  = 'on';
                        app.DrawImage.Visible="on";
                        app.NewDrawBTN.Visible="on";
                        app.ShapeVal.Visible = "on";
                        app.ShapeLabel.Visible = "on";
                         p1 = imread('Photos\manual\Img-Manual-1.png');
                         imagesc(app.DrawImage,p1);
                        app.DrawImage.XAxis.Visible = 'off';
                        app.DrawImage.YAxis.Visible = 'off';
                    end
                    app.Lateral_Pannel.Enable="off";
                    fig = uifigure('Name', 'Progress','CloseRequestFcn');
                    fig.WindowStyle = 'modal';
                    %fig.Resize = 'off';
                    fig.CloseRequestFcn;
                    fig.Position = [560 450 410 90];


                    d = uiprogressdlg(fig,'Icon','membrane.png', ...
                        'Interpreter','html');

                    steps = 100;
                    cmap = winter(steps)*100;
                    for step = 1:steps
                        r = num2str(cmap(step,1));
                        g = num2str(cmap(step,2));
                        b = num2str(cmap(step,3));
                        msg = ['<p style=color:rgb(' r '%,' g '%,' b '%)> Calculating... </p>'];
                        d.Message = msg;
                        d.Value = step/steps;
                        pause(0.05);
                    end
                    delete (fig);
                    close(d)

                    evalSubmit(app, []);
                end

                if (app.StartButton.Value == 1)
                    app.ConfusionAxes1.Visible="off";
                    cla(app.ConfusionAxes1,'reset')
                    app.ConfusionAxes1.XAxis.Visible = 'off';
                    app.ConfusionAxes1.YAxis.Visible = 'off';
                    set(app.ConfusionAxes1,'Color','none')
                    app.PerformanceAxes2.Position= [180,140,700,450];

                    app.AccuraccyLabel.Position = [25,520,70,22];
                    app.Accuraccy_Text.Position = [30,500,86,22];

                    app.RatiosLabel.Position = [25,350,46,22];
                    app.Ratios_Text.Position = [25,290,131,62];


                    app.EpochsLabel.Position = [25,460,53,23];
                    app.Epochs_Text.Position = [25,440,95,29];

                    app.InfosLabel.Position = [400,100,38,22];
                    app.Infos_text.Position = [450,80,193,45];

                elseif (app.ManualButton.Value == 1 || app.TestButton.Value == 1)
                    app.PerformanceAxes2.Visible ="off";
                    app.ConfusionAxes1.Position= [110,95,810,509];
                    app.AccuraccyLabel.Position = [25,520,70,22];
                    app.Accuraccy_Text.Position = [30,500,86,22];

                    app.RatiosLabel.Position = [25,350,46,22];
                    app.Ratios_Text.Position = [25,290,131,62];


                    app.EpochsLabel.Position = [25,400,53,23];
                    app.Epochs_Text.Position = [25,380,95,29];

                    app.InfosLabel.Position = [400,100,38,22];
                    app.Infos_text.Position = [450,80,193,45];
                else
                    app.ConfusionAxes1.Position= [420,93,500,500];
                    app.PerformanceAxes2.Position= [22,326,380,256];
                    app.Infos_text.Position = [94,255,193,45];
                    app.InfosLabel.Position = [32,278,38,22];
                end


                list = dir('Out/Redes/*.mat');     
                if(isempty(list)==1)
                    app.NeuralNetworkDropDown.Items = {'Default'};
                else
                    app.NeuralNetworkDropDown.Items = {list.name};
                end
                
            catch ME
                disp(ME);
                app.Lateral_Pannel.Enable="on";
                DefaultsButtonValueChanged(app, []);
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.ShowGraphs.Visible = 'off';
            app.Panel_cover.Visible = 'on';
        end

        % Callback function
        function ApresentacaoTAB(app, event)

            app.TabGroup.SelectedTab = app.ReportTab;
        end

        % Callback function
        function teamtab(app, event)

            app.TabGroup.SelectedTab = app.TeamTab;
        end

        % Button pushed function: ForumButton_2
        function MoodleButtonPushed(app, event)
            web('https://moodle.isec.pt/moodle/course/view.php?id=12670', '-browser')
        end

        % Button pushed function: ResetDrawing
        function ResetDrawingButtonPushed(app, event)
             draw(app, []);
        end

        % Button pushed function: NewDrawBTN
        function NewDrawBTNPushed(app, event)
                app.Panel_cover.Visible  = 'off';
                app.ManualDrawPanel.Visible ='on';
                 draw(app, []);
        end

        % Data changed function: HTML2
        function ForumButtonPushed(app, event)
            web('https://moodle.isec.pt/moodle/pluginfile.php/352059/mod_resource/content/4/IPM_4thAssignment_2021_2022.pdf', '-browser')
        end

        % Callback function
        function MATLABButtonPushed(app, event)
            web('https://www.mathworks.com/', '-browser')
        end

        % Callback function
        function FolderSelectValueChanged(app, event)
            value = app.FolderSelect.Value;
            if value == "Save"
                app.Folder.Visible = 'on';
            else
                app.Folder.Visible = 'off';
            end
        end

        % Button pushed function: InputsButton
        function InputsButtonPushed(app, event)
            web('/Photos/', '-browser')
        end

        % Button pushed function: OutputsButton
        function OutputsButtonPushed(app, event)
            web('out/', '-browser')
        end

        % Value changed function: DefaultsButton
        function DefaultsButtonValueChanged(app, event)
            if (app.ManualButton.Value == 1)
                app.Panel_cover.Visible  = 'off';
                app.ManualDrawPanel.Visible ='on';
                app.TrainSpinner.Enable=0;
                app.Func1DropDown.Enable=0;
                app.Layer1Spinner.Enable=0;
                app.Layer2Spinner.Enable=0;
                app.Layer3Spinner.Enable=0;
                app.validateSpinner.Enable=0;
                app.TestSpinner.Enable=0;
                app.EpochsSlider.Enable=0;
                     list = dir('Out/Redes/*.mat');     
                if(isempty(list)==1)
                    app.NeuralNetworkDropDown.Items = {'Default'};
                else
                    app.NeuralNetworkDropDown.Items = {list.name};
                end
                app.NeuralNetworkDropDown.Visible ='on'; 
                draw(app, []);

            end


            app.InfosLabel.Text="Infos:";
            app.LayersLabel.Text="Layers:";
            app.EpochsLabel.Text="Epochs:";
            app.Layer1Spinner.Value=1;
            app.Layer2Spinner.Value=1;
            app.Layer3Spinner.Value=1;

            if (app.StartButton.Value == 1)
                app.ConfusionAxes1.Visible=0;
                app.PerformanceAxes2.Position= [80,95,871,509];
            elseif (app.ManualButton.Value == 1 || app.TestButton.Value == 1)

                app.LayersLabel_text.Text = "";
                app.PerformanceAxes2.Visible ="on";
                app.ConfusionAxes1.Position= [80,95,871,509];
            else
                      app.ConfusionAxes1.Position= [400,93,520,509];
                    app.PerformanceAxes2.Position= [22,326,380,256];
            end
            app.Infos_text.Text="";
            app.Epochs_Text.Text="";
            app.Accuraccy_Text.Text="";
            app.Func1DropDown.Value="tansig";
            app.Func2DropDown.Value="purelin";
            app.Func3DropDown.Value="purelin";
            app.Layer1Spinner.Value=10;
            app.Layer2Spinner.Value=0;
            app.Layer3Spinner.Value=0;

            app.TrainSpinner.Value=70;
            app.validateSpinner.Value=15;
            app.TestSpinner.Value=15;
            app.EpochsSlider.Value=1;

            app.TrainSpinner.BackgroundColor = [0.85,0.90,0.91];
            app.validateSpinner.BackgroundColor = [0.85,0.90,0.91];
            app.TestSpinner.BackgroundColor = [0.85,0.90,0.91];
            app.EpochsLabel.Position = [241,213,53,23];
            app.Epochs_Text.Position = [295,211,95,29];
            app.AccuraccyLabel.Position = [31,214,70,22];
            app.Accuraccy_Text.Position = [111,214,86,22];

            app.RatiosLabel.Position = [233,161,46,22];
            app.Ratios_Text.Position = [280,121,131,62];


            app.TrainFuncDropDown.Value="traingd";
        
            if(app.TestButton.Value == 1 || app.ManualButton.Value == 1)
                app.TrainSpinner.Enable=0;
                app.Func1DropDown.Enable=0;
                app.Layer1Spinner.Enable=0;
                app.Layer2Spinner.Enable=0;
                app.Layer3Spinner.Enable=0;
                app.validateSpinner.Enable=0;
                app.TestSpinner.Enable=0;
                app.EpochsSlider.Enable=0;
                app.TrainFuncDropDown.Enable=0;
                app.ShowGraphs.Visible = 'off';
                app.Panel_cover.Visible = 'on';
                app.NeuralNetworkDropDown.Visible ='on';   
                app.NeuralNetworkLabel.Visible ='on'; 
                        if(app.TestButton.Value == 1)
                            app.DrawImage.Visible="off";
                                                                  cla(app.DrawImage,'reset')
            set(app.DrawImage,'Color','none')
                         app.NewDrawBTN.Visible="off";                            
                        else
                             app.DrawImage.Visible="on";
                         app.NewDrawBTN.Visible="on";                             
                        end
            else
                 app.NewDrawBTN.Visible="off";   
                app.DrawImage.Visible="off";
                app.ShowGraphs.Visible = 'off';
                app.Panel_cover.Visible = 'on';
                app.Func1DropDown.Enable=1;
                app.TrainSpinner.Enable=1;
                app.validateSpinner.Enable=1;
                app.EpochsSlider.Enable=1;
                app.Layer1Spinner.Enable=1;
                app.Layer2Spinner.Enable=1;
                app.Layer3Spinner.Enable=1;
                app.TestSpinner.Enable=1;
                app.TrainFuncDropDown.Enable=1;
                app.NeuralNetworkDropDown.Visible ='off'; 
                app.NeuralNetworkLabel.Visible ='off';
            end
                list = dir('Out/Redes/*.mat');     
                if(isempty(list)==1)
                    app.NeuralNetworkDropDown.Items = {'Default'};
                else
                    app.NeuralNetworkDropDown.Items = {list.name};
                end
            %app.StartButton.Value=1;
            %app.ManualButton.Value=0;
            %app.TestButton.Value=0;
            %app.TrainButton.Value=0;
        end

        % Callback function
        function MenuButtonPushed(app, event)
            app.ShowGraphs.Visible = 'off';
            app.Panel_cover.Visible = 'on';
            app.Protophotos.Visible = 'off';
            app.ProtoCover.Visible = 'on';
        end

        % Image clicked function: ISEC, Image_2
        function ISEC_Link(app, event)
            web('https://www.isec.pt/', '-browser')
        end

        % Image clicked function: Image34, Image35, Image36, Image37, 
        % ...and 1 other component
        function DALL_E(app, event)

            web('https://openai.com/', '-browser')
        end

        % Value changed function: TestSpinner, TrainSpinner, 
        % ...and 1 other component
        function RatioSpinnerValueChanged(app, event)

            sumRatio=app.TrainSpinner.Value + app.validateSpinner.Value+app.TestSpinner.Value;

            if(sumRatio >100)
                app.TrainSpinner.BackgroundColor = [1.00,0.46,0.46];
                app.validateSpinner.BackgroundColor = [1.00,0.46,0.46];
                app.TestSpinner.BackgroundColor = [1.00,0.46,0.46];
            end

            if (sumRatio <100)
                app.TrainSpinner.BackgroundColor = [1.00,0.78,0.49];
                app.validateSpinner.BackgroundColor = [1.00,0.78,0.49];
                app.TestSpinner.BackgroundColor = [1.00,0.78,0.49];
            end

            if (sumRatio == 100)
                app.TrainSpinner.BackgroundColor = [0.85,0.90,0.91];
                app.validateSpinner.BackgroundColor = [0.85,0.90,0.91];
                app.TestSpinner.BackgroundColor = [0.85,0.90,0.91];
            end
        end

        % Value changed function: Layer1Spinner, Layer2Spinner, 
        % ...and 1 other component
        function LayerSpinnerValueChanged(app, event)


            if(app.Layer1Spinner.Value ==0)
                app.Layer2Spinner.Value =0;
                app.Func2DropDown.Enable ="off";
                app.Layer3Spinner.Value =0;
                app.Func3DropDown.Enable ="off";
            else
                app.Func2DropDown.Enable ="on";

                if(app.Layer2Spinner.Value ==0)
                    app.Layer3Spinner.Value =0;
                    app.Layer3Spinner.Enable ="off";
                    app.Func3DropDown.Enable ="off";
                    app.Func2DropDown.Enable ="off";
                else
                    app.Func2DropDown.Enable ="on";
                    app.Layer3Spinner.Enable ="on";


                    if(app.Layer3Spinner.Value ==0)
                        app.Func3DropDown.Enable ="off";
                    else
                        app.Func3DropDown.Enable ="on";
                    end
                end
            end
        end

        % Selection changed function: PhaseButtonGroup
        function PhaseButtonGroupSelectionChanged(app, event)
            if (app.ManualButton.Value == 1 || app.TestButton.Value == 1)
                app.PerformanceAxes2.Visible=0;
                app.Panel_cover.Visible  = 'on';
                app.ShowGraphs.Visible  = 'off';
                app.ConfusionAxes1.Position= [110,95,810,509];


                app.AccuraccyLabel.Position = [25,520,70,22];
                app.Accuraccy_Text.Position = [30,500,86,22];

                app.RatiosLabel.Position = [25,350,46,22];
                app.Ratios_Text.Position = [25,290,131,62];


                app.EpochsLabel.Position = [25,460,53,23];
                app.Epochs_Text.Position = [25,440,95,29];

                app.InfosLabel.Position = [25,130,38,22];
                app.Infos_text.Position = [25,100,193,45];
                app.EpochsLabel.Visible = 1;
                app.InfosLabel.Visible = 1;
                app.Infos_text.Visible = 1;
                app.RatiosLabel.Visible = 1;
                app.Ratios_Text.Visible = 1;
                app.TrainFuncDropDown.Enable=0;
                app.InfosLabel.Text=" ";
                app.Infos_text.Text=" ";
                app.LayersLabel.Text=" ";
                app.LayersLabel_text.Text=" ";
                app.Layer1Spinner.Value=0;
                app.Layer2Spinner.Value=0;
                app.Layer3Spinner.Value=0;

                app.TrainSpinner.Value=0;
                app.validateSpinner.Value=0;
                app.TestSpinner.Value=100;

                app.EpochsSlider.Value=1;
                app.ShowGraphs.Visible  = 'on';

                app.TrainSpinner.Enable=0;
                app.Func1DropDown.Enable=0;
                app.Func2DropDown.Enable=0;
                app.Func3DropDown.Enable=0;
                app.Layer1Spinner.Enable=0;
                app.Layer2Spinner.Enable=0;
                app.Layer3Spinner.Enable=0;
                app.validateSpinner.Enable=0;
                app.TestSpinner.Enable=0;
                app.EpochsSlider.Enable=0;

                 list = dir('Out/Redes/*.mat');

                if(isempty(list)==1)
                    app.NeuralNetworkDropDown.Items = {'Default'};
                else
                    app.NeuralNetworkDropDown.Items = {list.name};
                end
                
                app.NeuralNetworkDropDown.Visible ='on';   
                app.NeuralNetworkLabel.Visible ='on'; 
                if (app.ManualButton.Value == 1)
                    app.Panel_cover.Visible  = 'off';
                    app.ManualDrawPanel.Visible ='on';
                    app.DrawImage.Visible="on";
                    draw(app, []);
                        app.ShapeVal.Visible = "on";
                        app.ShapeLabel.Visible = "on";


                else
                          app.DrawImage.Visible="off";
                                                               cla(app.DrawImage,'reset')
            set(app.DrawImage,'Color','none')
                                    app.DrawImage.XAxis.Visible = 'off';
                        app.DrawImage.YAxis.Visible = 'off';
                         app.ShapeVal.Visible = "off";
                         app.ShapeLabel.Visible = "off";
                    app.ManualDrawPanel.Visible ="off";
                    if(app.ShowGraphs.Visible == "off")
                        app.Panel_cover.Visible ='on';
                    end
                end
            elseif (app.StartButton.Value == 1)
                app.ConfusionAxes1.Visible="off";
                app.PerformanceAxes2.Position= [80,95,871,509];
                DefaultsButtonValueChanged(app, []);
                app.NeuralNetworkDropDown.Visible ='off';   
                app.NeuralNetworkLabel.Visible ='off'; 
            else

                app.PerformanceAxes2.Visible=1;
                DefaultsButtonValueChanged(app, []);
                app.Panel_cover.Visible  = 'on';
                app.ShowGraphs.Visible  = 'off';
                app.NeuralNetworkDropDown.Visible ='off';   
                app.NeuralNetworkLabel.Visible ='off'; 
            end
        end

        % Value changed function: NeuralNetworkDropDown
        function NeuralNetworkDropDownValueChanged(app, event)

% Create figure and components
     
                list = dir('Out/Redes/*.mat');
      
                app.NeuralNetworkDropDown.Items = {list.name};
        end

        % Button down function: StudyResultsTab
        function StudyResultsTabButtonDown(app, event)
            try
                     t = readtable("extras/estudoNN.xlsx","Sheet",1,'Format','auto');
            isnum = varfun(@isnumeric,t,'output','uniform');  % logical vector that ID's numeric columns
            fillVal = repmat({''},size(isnum));  
            fillVal(isnum) = {0}; 
            t = fillmissing(t,'Constant',fillVal);  % Fill the missing data
            app.UITable.Data = t;
            app.UITable.ColumnName = t.Properties.VariableNames;
                      %winopen("estudoNN.xlsx");

            %copyobj(t, app.ExcelAxes);
            catch ME
                disp(ME);
            end
  
        end

        % Value changed function: OpenFileButton
        function OpenFileButtonValueChanged(app, event)
            winopen('estudoNN.xlsx')
        end

        % Value changed function: Enunciado
        function EnunciadoValueChanged(app, event)
            winopen('extras/EnunciadoTrabPratico_CR2022.pdf')
        end

        % Value changed function: Relatorio
        function RelatorioValueChanged(app, event)
            winopen('extras/Relatorio_TP_CR.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImageDetectUIFigure = uifigure('Visible', 'off');
            app.ImageDetectUIFigure.Name = 'ImageDetect';
            app.ImageDetectUIFigure.Icon = fullfile(pathToMLAPP, 'extras', '2x-no-mark.jpg');
            app.TabGroup = uitabgroup(app.ImageDetectUIFigure);
            app.NeuralNetworkTab = uitab(app.TabGroup);
            app.NeuralNetworkTab.Title = 'Neural Network';
            app.Lateral_Pannel = uipanel(app.NeuralNetworkTab);
            app.OutputsButton = uibutton(app.Lateral_Pannel, 'push');
            app.OutputsButton.ButtonPushedFcn = createCallbackFcn(app, @OutputsButtonPushed, true);
            app.OutputsButton.Icon = fullfile(pathToMLAPP, 'extras', 'browse2.png');
            app.OutputsButton.Text = 'Outputs';
            app.InputsButton = uibutton(app.Lateral_Pannel, 'push');
            app.InputsButton.ButtonPushedFcn = createCallbackFcn(app, @InputsButtonPushed, true);
            app.InputsButton.Icon = fullfile(pathToMLAPP, 'extras', 'browse.png');
            app.InputsButton.Text = 'Inputs';
            app.DefaultsButton = uibutton(app.Lateral_Pannel, 'state');
            app.DefaultsButton.ValueChangedFcn = createCallbackFcn(app, @DefaultsButtonValueChanged, true);
            app.DefaultsButton.Text = 'Defaults';
            app.SubmitButton_2 = uibutton(app.Lateral_Pannel, 'push');
            app.SubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton_2.Text = 'Submit >';
            app.BackButton = uibutton(app.Lateral_Pannel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '< Back';
            app.TrainFuncDropDownLabel = uilabel(app.Lateral_Pannel);
            app.TrainFuncDropDownLabel.Text = 'Train Func:';
            app.TrainFuncDropDown = uidropdown(app.Lateral_Pannel);
            app.TrainFuncDropDown.Items = {'traingd', 'traingdx', 'trainrp', 'trainoss', 'traincgb', 'trainlm', 'trainbfg'};
            app.TrainFuncDropDown.Value = 'traingd';
            app.EpochsSliderLabel = uilabel(app.Lateral_Pannel);
            app.EpochsSliderLabel.Text = 'Epochs';
            app.EpochsSlider = uislider(app.Lateral_Pannel);
            app.EpochsSlider.Limits = [1 1000];
            app.EpochsSlider.MajorTicks = [1 200 400 600 800 1000];
            app.EpochsSlider.MinorTicks = [];
            app.EpochsSlider.Value = 1;
            app.PhaseButtonGroup = uibuttongroup(app.Lateral_Pannel);
            app.PhaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhaseButtonGroupSelectionChanged, true);
            app.PhaseButtonGroup.Title = 'Phase:';
            app.StartButton = uiradiobutton(app.PhaseButtonGroup);
            app.StartButton.Text = 'Start';
            app.StartButton.Value = true;
            app.TrainButton = uiradiobutton(app.PhaseButtonGroup);
            app.TrainButton.Text = 'Train';
            app.TestButton = uiradiobutton(app.PhaseButtonGroup);
            app.TestButton.Text = 'Test';
            app.ManualButton = uiradiobutton(app.PhaseButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.RatiosPanel = uipanel(app.Lateral_Pannel);
            app.RatiosPanel.Title = 'Ratios';
            app.TrainSpinnerLabel = uilabel(app.RatiosPanel);
            app.TrainSpinnerLabel.Text = 'Train';
            app.TrainSpinner = uispinner(app.RatiosPanel);
            app.TrainSpinner.Limits = [0 100];
            app.TrainSpinner.ValueChangedFcn = createCallbackFcn(app, @RatioSpinnerValueChanged, true);
            app.TrainSpinner.Value = 70;
            app.validateSpinnerLabel = uilabel(app.RatiosPanel);
            app.validateSpinnerLabel.Text = 'validate';
            app.validateSpinner = uispinner(app.RatiosPanel);
            app.validateSpinner.Limits = [0 100];
            app.validateSpinner.ValueChangedFcn = createCallbackFcn(app, @RatioSpinnerValueChanged, true);
            app.validateSpinner.Value = 15;
            app.TestSpinnerLabel = uilabel(app.RatiosPanel);
            app.TestSpinnerLabel.Text = 'Test';
            app.TestSpinner = uispinner(app.RatiosPanel);
            app.TestSpinner.Limits = [0 100];
            app.TestSpinner.ValueChangedFcn = createCallbackFcn(app, @RatioSpinnerValueChanged, true);
            app.TestSpinner.Value = 15;
            app.LayersPanel = uipanel(app.Lateral_Pannel);
            app.LayersPanel.Title = 'Layers';
            app.Layer1SpinnerLabel = uilabel(app.LayersPanel);
            app.Layer1SpinnerLabel.Text = 'Layer 1';
            app.Layer1Spinner = uispinner(app.LayersPanel);
            app.Layer1Spinner.Limits = [0 100];
            app.Layer1Spinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.Layer1Spinner.Value = 10;
            app.Layer2SpinnerLabel = uilabel(app.LayersPanel);
            app.Layer2SpinnerLabel.Text = 'Layer 2';
            app.Layer2Spinner = uispinner(app.LayersPanel);
            app.Layer2Spinner.Limits = [0 100];
            app.Layer2Spinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.Layer3SpinnerLabel = uilabel(app.LayersPanel);
            app.Layer3SpinnerLabel.Text = 'Layer 3';
            app.Layer3Spinner = uispinner(app.LayersPanel);
            app.Layer3Spinner.Limits = [0 100];
            app.Layer3Spinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.Layer3Spinner.Enable = 'off';
            app.Func1DropDownLabel = uilabel(app.Lateral_Pannel);
            app.Func1DropDownLabel.Text = 'Func1:';
            app.Func1DropDown = uidropdown(app.Lateral_Pannel);
            app.Func1DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.Func1DropDown.Value = 'tansig';
            app.Func2DropDownLabel = uilabel(app.Lateral_Pannel);
            app.Func2DropDownLabel.Text = 'Func2:';
            app.Func2DropDown = uidropdown(app.Lateral_Pannel);
            app.Func2DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.Func2DropDown.Enable = 'off';
            app.Func2DropDown.Value = 'purelin';
            app.Func3DropDownLabel = uilabel(app.Lateral_Pannel);
            app.Func3DropDownLabel.Text = 'Func3:';
            app.Func3DropDown = uidropdown(app.Lateral_Pannel);
            app.Func3DropDown.Items = {'tansig', 'purelin', 'logsig'};
            app.Func3DropDown.Enable = 'off';
            app.Func3DropDown.Value = 'purelin';
            app.NeuralNetworkDropDown = uidropdown(app.Lateral_Pannel);
            app.NeuralNetworkDropDown.Items = {'Default'};
            app.NeuralNetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuralNetworkDropDownValueChanged, true);
            app.NeuralNetworkDropDown.Visible = 'off';
            app.NeuralNetworkDropDown.Value = 'Default';
            app.NeuralNetworkLabel = uilabel(app.Lateral_Pannel);
            app.NeuralNetworkLabel.Visible = 'off';
            app.NeuralNetworkLabel.Text = 'Neural Network';
            app.ShowGraphs = uipanel(app.NeuralNetworkTab);
            app.Panel_6 = uipanel(app.ShowGraphs);
            app.Panel_5 = uipanel(app.ShowGraphs);
            app.PerformanceAxes2 = uiaxes(app.Panel_5);
            title(app.PerformanceAxes2, 'Performance')
            app.PerformanceAxes2.XTick = [];
            app.PerformanceAxes2.XTickLabel = {'[]'};
            app.PerformanceAxes2.YTick = [];
            app.PerformanceAxes2.YTickLabel = '';
            app.PerformanceAxes2.ZTick = [];
            app.PerformanceAxes2.ZTickLabel = '';
            app.PerformanceAxes2.Visible = 'off';
            app.ConfusionAxes1 = uiaxes(app.Panel_5);
            title(app.ConfusionAxes1, 'Confusion')
            app.ConfusionAxes1.XTick = [];
            app.ConfusionAxes1.XTickLabel = {'[]'};
            app.ConfusionAxes1.YTick = [];
            app.ConfusionAxes1.YTickLabel = '';
            app.ConfusionAxes1.ZTick = [];
            app.ConfusionAxes1.ZTickLabel = '';
            app.ConfusionAxes1.Visible = 'off';
            app.DrawImage = uiaxes(app.Panel_5);
            app.DrawImage.XTick = [];
            app.DrawImage.YTick = [];
            app.DrawImage.ZTick = [];
            app.DrawImage.Visible = 'off';
            app.AccuraccyLabel = uilabel(app.Panel_5);
            app.AccuraccyLabel.Text = 'Accuraccy:';
            app.LayersLabel_text = uilabel(app.Panel_5);
            app.LayersLabel_text.Text = 'Layers Text';
            app.LayersLabel = uilabel(app.Panel_5);
            app.LayersLabel.Text = 'Layers:';
            app.Accuraccy_Text = uilabel(app.Panel_5);
            app.Accuraccy_Text.Text = 'Accuraccy Text';
            app.ISEC = uiimage(app.Panel_5);
            app.ISEC.ImageClickedFcn = createCallbackFcn(app, @ISEC_Link, true);
            app.ISEC.ImageSource = fullfile(pathToMLAPP, 'extras', 'phISEC.png');
            app.Direitos1 = uilabel(app.Panel_5);
            app.Direitos1.Text = 'Direitos reservados: Desenvolvido em contexo académico - Disciplina CR';
            app.RatiosLabel = uilabel(app.Panel_5);
            app.RatiosLabel.Text = 'Ratios:';
            app.EpochsLabel = uilabel(app.Panel_5);
            app.EpochsLabel.Text = 'Epochs:';
            app.Ratios_Text = uilabel(app.Panel_5);
            app.Ratios_Text.Text = 'Ratios Text';
            app.Epochs_Text = uilabel(app.Panel_5);
            app.Epochs_Text.Text = 'Epoch Text';
            app.InfosLabel = uilabel(app.Panel_5);
            app.InfosLabel.Text = 'Infos:';
            app.Infos_text = uilabel(app.Panel_5);
            app.Infos_text.Text = 'Infos Text';
            app.NewDrawBTN = uibutton(app.Panel_5, 'push');
            app.NewDrawBTN.ButtonPushedFcn = createCallbackFcn(app, @NewDrawBTNPushed, true);
            app.NewDrawBTN.Visible = 'off';
            app.NewDrawBTN.Text = 'New';
            app.ShapeLabel = uilabel(app.Panel_5);
            app.ShapeLabel.Text = 'Shape: ';
            app.ShapeVal = uilabel(app.Panel_5);
            app.ShapeVal.Text = 'Shape';
            app.Image32_2 = uiimage(app.NeuralNetworkTab);
            app.Image32_2.ImageSource = fullfile(pathToMLAPP, 'extras', 'Mental_image.PNG');
            app.Panel_cover = uipanel(app.NeuralNetworkTab);
            app.Image30 = uiimage(app.Panel_cover);
            app.Image30.ImageSource = fullfile(pathToMLAPP, 'extras', 'Diapositivo11.JPG');
            app.ManualDrawPanel = uipanel(app.NeuralNetworkTab);
            app.ManualDrawPanel.Title = 'Manual Draw';
            app.ManualDrawPanel.Visible = 'off';
            app.Draw_Axes = uiaxes(app.ManualDrawPanel);
            app.Draw_Axes.ALim = [0 2];
            app.Draw_Axes.XTick = [];
            app.Draw_Axes.XTickLabel = {'[]'};
            app.Draw_Axes.YTick = [];
            app.Draw_Axes.YTickLabel = '';
            app.Draw_Axes.ZTick = [];
            app.Draw_Axes.ZTickLabel = '';
            app.ResetDrawing = uibutton(app.ManualDrawPanel, 'push');
            app.ResetDrawing.ButtonPushedFcn = createCallbackFcn(app, @ResetDrawingButtonPushed, true);
            app.ResetDrawing.Text = 'Clean';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.Panel_3 = uipanel(app.ReportTab);
            app.HTML2 = uihtml(app.Panel_3);
            app.HTML2.HTMLSource = 'extras/Relatorio_TP_CR.pdf';
            app.HTML2.DataChangedFcn = createCallbackFcn(app, @ForumButtonPushed, true);
            app.Relatorio = uibutton(app.Panel_3, 'state');
            app.Relatorio.ValueChangedFcn = createCallbackFcn(app, @RelatorioValueChanged, true);
            app.Relatorio.Text = 'Open File';
            app.AssignementTab = uitab(app.TabGroup);
            app.AssignementTab.Title = 'Assignement';
            app.Panel_7 = uipanel(app.AssignementTab);
            app.HTML2_2 = uihtml(app.Panel_7);
            app.HTML2_2.HTMLSource = 'extras/EnunciadoTrabPratico_CR2022.pdf';
            app.Enunciado = uibutton(app.Panel_7, 'state');
            app.Enunciado.ValueChangedFcn = createCallbackFcn(app, @EnunciadoValueChanged, true);
            app.Enunciado.Text = 'Open File';
            app.StudyResultsTab = uitab(app.TabGroup);
            app.StudyResultsTab.Title = 'Study Results';
            app.StudyResultsTab.ButtonDownFcn = createCallbackFcn(app, @StudyResultsTabButtonDown, true);
            app.UITable = uitable(app.StudyResultsTab);
            app.UITable.ColumnName = {'Close exel file to open table'};
            app.UITable.RowName = {};
            app.OpenFileButton = uibutton(app.StudyResultsTab, 'state');
            app.OpenFileButton.ValueChangedFcn = createCallbackFcn(app, @OpenFileButtonValueChanged, true);
            app.OpenFileButton.Text = 'Open File';
            app.Lamp = uilamp(app.StudyResultsTab);
            app.TeamTab = uitab(app.TabGroup);
            app.TeamTab.Title = 'Team';
            app.Autores = uipanel(app.TeamTab);
            app.Autores.Title = 'Development Team';
            app.Thp = uiimage(app.Autores);
            app.Thp.ImageSource = fullfile(pathToMLAPP, 'extras', 'Hugo.jpg');
            app.CarlosSantos = uilabel(app.Autores);
            app.CarlosSantos.Text = 'Carlos Santos ';
            app.HugoFerreira = uilabel(app.Autores);
            app.HugoFerreira.Text = 'Hugo Ferreira ';
            app.Direitos2 = uilabel(app.Autores);
            app.Direitos2.Text = '2021/2022';
            app.Image_2 = uiimage(app.Autores);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @ISEC_Link, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'extras', 'phISEC.png');
            app.Image2 = uiimage(app.Autores);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'extras', 'Carlos.JPG');
            app.obrigado = uilabel(app.Autores);
            app.obrigado.Text = 'Thank you for using our application!!';
            app.Label_4 = uilabel(app.Autores);
            app.Label_4.Text = 'To obtain more information about MATLAB or for any peak of interest on the subject, there is a button on this tab which, when clicked, will redirect you to the MathWorks site which belongs to the group responsible for its development.';
            app.Hyperlink = uihyperlink(app.Autores);
            app.Hyperlink.URL = 'https://www.mathworks.com/';
            app.Hyperlink.Text = 'MATLAB';
            app.Hyperlink2 = uihyperlink(app.Autores);
            app.Hyperlink2.URL = 'a2003035578@isec.pt';
            app.Hyperlink2.Text = 'a2003035578@isec.pt';
            app.Hyperlink3 = uihyperlink(app.Autores);
            app.Hyperlink3.Text = 'a2020128305@isec.pt';
            app.Image35 = uiimage(app.Autores);
            app.Image35.ImageClickedFcn = createCallbackFcn(app, @DALL_E, true);
            app.Image35.ImageSource = fullfile(pathToMLAPP, 'extras', 'phTP_b.jpg');
            app.Image34 = uiimage(app.Autores);
            app.Image34.ImageClickedFcn = createCallbackFcn(app, @DALL_E, true);
            app.Image34.ImageSource = fullfile(pathToMLAPP, 'extras', 'Output05-23-2022_20_31_41.png');
            app.Image38 = uiimage(app.Autores);
            app.Image38.ImageClickedFcn = createCallbackFcn(app, @DALL_E, true);
            app.Image38.ImageSource = fullfile(pathToMLAPP, 'extras', 'Output05-23-2022_20_13_50.png');
            app.Image37 = uiimage(app.Autores);
            app.Image37.ImageClickedFcn = createCallbackFcn(app, @DALL_E, true);
            app.Image37.ImageSource = fullfile(pathToMLAPP, 'extras', 'Output05-21-2022_20_56_05.png');
            app.Image36 = uiimage(app.Autores);
            app.Image36.ImageClickedFcn = createCallbackFcn(app, @DALL_E, true);
            app.Image36.ImageSource = fullfile(pathToMLAPP, 'extras', 'Dall-e.png');
            app.ForumButton_2 = uibutton(app.Autores, 'push');
            app.ForumButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoodleButtonPushed, true);
            app.ForumButton_2.Icon = fullfile(pathToMLAPP, 'extras', 'moodle.png');
            app.ForumButton_2.Text = 'TP - Information';
            % Show the figure after all components are created
            app.ImageDetectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCH_images

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageDetectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageDetectUIFigure)
        end
    end
end